classdef BlancaeNet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BlancaeNetAppUIFigure  matlab.ui.Figure
        FolderTxt              matlab.ui.control.TextArea
        SelectfolderButton     matlab.ui.control.Button
        VideoPanel             matlab.ui.container.Panel
        TextArea_Video         matlab.ui.control.TextArea
        FindButton_Video       matlab.ui.control.Button
        AudioPanel             matlab.ui.container.Panel
        TextArea_Audio         matlab.ui.control.TextArea
        FindButton_Audio       matlab.ui.control.Button
        Image5                 matlab.ui.control.Image
        Image4                 matlab.ui.control.Image
        Image3                 matlab.ui.control.Image
        Image2                 matlab.ui.control.Image
        Image                  matlab.ui.control.Image
        BlancaeNetLabel        matlab.ui.control.Label
        Label                  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Data_folder='' % Description
        KiwiAtlapetesNet=load([pwd '/CNN_and_Data_Atlapetes.mat'],'KiwiAtlapetesNet');
        krgb=load([pwd '/krgb.mat'],'krgb');
        trainedNet=load([pwd '/Resnet18_AtlapetesBlancae.mat'],'trainedNet');
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %create_folders
            %cvs frames
           dir_aux=[pwd '/frame_rates.csv'];
           if(exist(dir_aux)~=0)
                delete(dir_aux);
           end


            %Dir Kiwinet
           dir_aux=[pwd '/Kiwinet'];
           if(exist(dir_aux)~=7)
                mkdir(dir_aux);
           end

            %Spectograms
            dir_aux=[pwd '/Kiwinet/Espectogramas'];
            if(exist(dir_aux)==7)%7 porque en la doc de la fun , dice que return 7 cuando es un folder
                delete([pwd '/Kiwinet/Espectogramas/*']) 
            else
                mkdir(dir_aux);
            end

            %Dir MultiLayerRPCA_CNN
           dir_aux=[pwd '/MultiLayerRPCA_CNN'];
           if(exist(dir_aux)~=7)
                mkdir(dir_aux);
           end

            %Dir frames
           dir_aux=[pwd '/MultiLayerRPCA_CNN/Frames/'];
           if(exist(dir_aux)==7)
               subfolders = dir(dir_aux);
               subfolders = subfolders([subfolders.isdir]); % selecciona solo los subdirectorios
               for i = 3:length(subfolders)
                       rmdir(fullfile(dir_aux, subfolders(i).name),'s'); %
               end
           else mkdir(dir_aux);
           end

           %Dir algoritm
           dir_aux=[pwd '/MultiLayerRPCA_CNN/algoritmo'];
           if(exist(dir_aux)==7)
               subfolders = dir(dir_aux);
               subfolders = subfolders([subfolders.isdir]); % selecciona solo los subdirectorios
               for i = 3:length(subfolders)
                       rmdir(fullfile(dir_aux, subfolders(i).name),'s'); %
               end
           else mkdir(dir_aux);
           end
            
           %Dir Recortes
           dir_aux=[pwd '/MultiLayerRPCA_CNN/Recortes'];
           if(exist(dir_aux)==7)
               subfolders = dir(dir_aux);
               subfolders = subfolders([subfolders.isdir]); % selecciona solo los subdirectorios
               for i = 3:length(subfolders)
                       rmdir(fullfile(dir_aux, subfolders(i).name),'s'); %
               end
           else mkdir(dir_aux);
           end
           
           %cvs Video_Result
           dir_aux=[pwd '/MultiLayerRPCA_CNN/Video_Result.csv'];
           if(exist(dir_aux)~=0)
                delete(dir_aux);
           end

           %cvs Audio_Result
           dir_aux=[pwd '/Kiwinet/Results_Audio.csv'];
           if(exist(dir_aux)~=0)
                delete(dir_aux);
           end
        
        end

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(app, event)
            app.Data_folder=uigetdir(pwd,'Select data folder');
            app.FolderTxt.Value=app.Data_folder;
            app.TextArea_Audio.Value=" ";
            app.TextArea_Video.Value=" ";
        end

        % Callback function: FindButton_Audio, TextArea_Audio
        function FindButtonPushed(app, event)
            %Audio
            app.TextArea_Audio.Value=" ";
            %Verificar archivo en el folder
            folder = app.Data_folder;
            files = dir (folder);
            Error_folder=0;
            if isempty(files)
                Error_folder=1;
            else
                for i = 3:length(files)
                    [~,~,ext] = fileparts(files(i).name);
                    if ~strcmpi(ext, '.wav')
                        Error_folder=1;
                        break
                    else
                        Error_folder=0;
                    end
                end
            end

            if Error_folder==0 %Si el folder es correcto
            try
               KiwiNet(app.Data_folder,app.KiwiAtlapetesNet,app.krgb)
               app.TextArea_Audio.Value="Result in /Kiwinet/Results_Audio.cvs";
            catch ME
               errordlg(ME.identifier);
               errordlg('Error #001');
            end
            else %si no es correcto
                errordlg('Error #002 Invalid Folder');
            end
            
        end

        % Button pushed function: FindButton_Video
        function FindButton_VideoPushed(app, event)
            %Video
            app.TextArea_Video.Value=" ";
            %Verificar archivo en el folder
            folder = app.Data_folder;
            files = dir(folder);
            Error_folder=0;
            if isempty(files)
                Error_folder=1;
            else
                for i = 3:length(files)
                    [~,~,ext] = fileparts(files(i).name);
                    if ~strcmpi(ext, '.mp4') && ~strcmpi(ext, '.mov')
                        Error_folder=1;
                        break
                    else
                        Error_folder=0;
                    end
                end
            end
            if Error_folder==0
            MultiLayerRPCA_Interfaz(app.Data_folder);
            Recortar_imagenes_boundingbox();
            Prueba_red_indv_resnet18(app.trainedNet);
            app.TextArea_Video.Value="Result in /MultiLayerRPCA_CNN/Video_Result.csv";
            
            else
                errordlg('Error #002 Invalid Folder');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BlancaeNetAppUIFigure = uifigure('Visible', 'off');
            app.BlancaeNetAppUIFigure.Name = 'BlancaeNet App';
            app.BlancaeNetAppUIFigure.Icon = fullfile(pathToMLAPP, 'Icon_Atl_Bl.jpg');
            app.Label = uilabel(app.BlancaeNetAppUIFigure);
            app.Label.Text = '';
            app.BlancaeNetLabel = uilabel(app.BlancaeNetAppUIFigure);
            app.BlancaeNetLabel.Text = 'BlancaeNet';
            app.Image = uiimage(app.BlancaeNetAppUIFigure);
            app.Image.ImageSource = 'Escudo-UdeA_2.png';
            app.Image2 = uiimage(app.BlancaeNetAppUIFigure);
            app.Image2.ImageSource = 'fotoefectos.com_.png';
            app.Image3 = uiimage(app.BlancaeNetAppUIFigure);
            app.Image3.ImageSource = 'LOGO Neotropical Innovation WHITE.png';
            app.Image4 = uiimage(app.BlancaeNetAppUIFigure);
            app.Image4.ImageSource = 'IAvH_1.png';
            app.Image5 = uiimage(app.BlancaeNetAppUIFigure);
            app.Image5.ImageSource = 'Montañerito Paisa.jpg';
            app.AudioPanel = uipanel(app.BlancaeNetAppUIFigure);
            app.AudioPanel.Title = 'Audio';
            app.FindButton_Audio = uibutton(app.AudioPanel, 'push');
            app.FindButton_Audio.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.FindButton_Audio.Text = 'Find';
            app.TextArea_Audio = uitextarea(app.AudioPanel);
            app.TextArea_Audio.ValueChangingFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.VideoPanel = uipanel(app.BlancaeNetAppUIFigure);
            app.VideoPanel.Title = 'Video';
            app.FindButton_Video = uibutton(app.VideoPanel, 'push');
            app.FindButton_Video.ButtonPushedFcn = createCallbackFcn(app, @FindButton_VideoPushed, true);
            app.FindButton_Video.Text = 'Find';
            app.TextArea_Video = uitextarea(app.VideoPanel);
            app.SelectfolderButton = uibutton(app.BlancaeNetAppUIFigure, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.FolderTxt = uitextarea(app.BlancaeNetAppUIFigure);
            % Show the figure after all components are created
            app.BlancaeNetAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BlancaeNet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BlancaeNetAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BlancaeNetAppUIFigure)
        end
    end
end